classdef AttendanceApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ExportAttendanceButton  matlab.ui.control.Button
        StatusLabel             matlab.ui.control.Label
        ExitButton              matlab.ui.control.Button
        StartAttendanceButton   matlab.ui.control.Button
        TrainCNNButton          matlab.ui.control.Button
        RegisterStudentButton   matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        isCameraRunning = false;
    end
    
    methods (Access = private)
        
        function startCamera(app)

            app.isCameraRunning = true;

            data = load('model/cnnModel.mat');
            net = data.net;
            
            cam = webcam;
            faceDetector = vision.CascadeObjectDetector();
            
            while app.isCameraRunning
            
                img = snapshot(cam);
                bbox = step(faceDetector,img);
            
                if ~isempty(bbox)
            
                    face = imcrop(img,bbox(1,:));
            
                    if size(face,3)==3
                        face = rgb2gray(face);
                    end
            
                    face = imresize(face,[100 100]);
                    face = im2single(face);
            
                    [label,scores] = classify(net,face);
            
                    confidence = max(scores)*100;
                    roll = char(label);
            
                    if confidence > 85
            
                        img = insertShape(img,'Rectangle',bbox(1,:), ...
                            'Color','green','LineWidth',3);
            
                        img = insertText(img,[bbox(1,1) bbox(1,2)-30], ...
                            sprintf('%s | %.2f%%',roll,confidence), ...
                            'FontSize',18,'BoxColor','green');
            
                        status = markAttendance(roll);
            
                        if strcmp(status,'MARKED')
                            app.StatusLabel.Text = "Attendance Marked";
                        else
                            app.StatusLabel.Text = "Already Marked";
                        end
            
                    else
                        app.StatusLabel.Text = "Unknown Person";
                    end
                end
            
                imshow(img,'Parent',app.UIAxes);
                drawnow;
            
            end
            clear cam;
            end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartAttendanceButton
        function StartAttendanceButtonPushed(app, event)
            startCamera(app);
        end

        % Button pushed function: RegisterStudentButton
        function RegisterStudentButtonPushed(app, event)
            registerStudent();
        end

        % Button pushed function: TrainCNNButton
        function TrainCNNButtonPushed(app, event)
            trainCNNModel();
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            app.isCameraRunning = false;
            pause(0.3);   % allow camera loop to stop
            delete(app);
        end

        % Button pushed function: ExportAttendanceButton
        function ExportAttendanceButtonPushed(app, event)
            folder = 'attendance';
            files = dir(fullfile(folder,'*.csv'));
            
            if isempty(files)
                app.StatusLabel.Text = "No attendance records found";
                return
            end
            
            allData = table();
            
            for i = 1:length(files)
            
                filePath = fullfile(folder,files(i).name);
                data = readtable(filePath);
            
                date = repmat(string(files(i).name(1:10)),height(data),1);
            
                data.Date = date;
            
                allData = [allData; data];
            
            end
            
            excelFile = 'Attendance_Report.xlsx';
            writetable(allData,excelFile);
            
            app.StatusLabel.Text = "Attendance exported to Excel";

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'CameraAxes')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RegisterStudentButton = uibutton(app.UIFigure, 'push');
            app.RegisterStudentButton.ButtonPushedFcn = createCallbackFcn(app, @RegisterStudentButtonPushed, true);
            app.RegisterStudentButton.Text = 'Register Student';
            app.TrainCNNButton = uibutton(app.UIFigure, 'push');
            app.TrainCNNButton.ButtonPushedFcn = createCallbackFcn(app, @TrainCNNButtonPushed, true);
            app.TrainCNNButton.Text = 'Train CNN';
            app.StartAttendanceButton = uibutton(app.UIFigure, 'push');
            app.StartAttendanceButton.ButtonPushedFcn = createCallbackFcn(app, @StartAttendanceButtonPushed, true);
            app.StartAttendanceButton.Text = 'Start Attendance';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'StatusLabel';
            app.ExportAttendanceButton = uibutton(app.UIFigure, 'push');
            app.ExportAttendanceButton.ButtonPushedFcn = createCallbackFcn(app, @ExportAttendanceButtonPushed, true);
            app.ExportAttendanceButton.Text = 'Export Attendance';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AttendanceApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end